classdef cvApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        QuitMenu                    matlab.ui.container.Menu
        SettingsMenu                matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        MainContentPanel            matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        VisualizationStyleDropDown  matlab.ui.control.DropDown
        VisualizationStyleDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        ContentPanel1 matlab.ui.container.Panel
        ContentPanel2 matlab.ui.container.Panel
        ContentPanel3 matlab.ui.container.Panel

        P1Slider      matlab.ui.control.Slider
        P1ImageAxes   matlab.ui.control.UIAxes

        ImageFiles  % Cell array of full image file paths
        ImageDates  % datetime array parsed from filenames
        Images     % Cell array to store loaded images (optional, for faster access)
    end
    
    methods (Access = private)

        function sliderValueChanged(app, src, event)
            idx = round(src.Value); % get slider value, round to nearest integer index
            % Make sure idx is within bounds
            idx = max(1, min(idx, length(app.Images)));
            
            % Update slider value (in case it was fractional)
            src.Value = idx;
            
            % Show the selected image in axes
            imshow(app.Images{idx}, 'Parent', app.P1ImageAxes);
        end
        
        function loadImagesFromFolder(app)
            % Ask user to select a folder
            folderPath = uigetdir(pwd, 'Select Folder Containing Images');
            if folderPath == 0
                % User canceled
                return
            end
            
            % Get list of JPG files matching pattern YYYY_MM.jpg
            files = dir(fullfile(folderPath, '*.jpg'));
            
            % Filter files matching pattern YYYY_MM.jpg
            % Regular expression for filename like "2023_04.jpg"
            pattern = '^\d{4}_\d{2}\.jpg$';
            
            validFiles = {};
            validDates = datetime.empty(1,0);
            
            for k = 1:length(files)
                fname = files(k).name;
                if ~isempty(regexp(fname, pattern, 'once'))
                    % Parse date from filename: YYYY_MM
                    parts = split(fname, {'_', '.'});
                    yearNum = str2double(parts{1});
                    monthNum = str2double(parts{2});
                    dt = datetime(yearNum, monthNum, 1);
                    
                    validFiles{end+1} = fullfile(folderPath, fname);
                    validDates(end+1) = dt;
                end
            end
            
            if isempty(validFiles)
                uialert(app.UIFigure, 'No valid images found with pattern YYYY_MM.jpg', 'No Images');
                return
            end
            
            % Sort images by date
            [validDates, sortIdx] = sort(validDates);
            validFiles = validFiles(sortIdx);
            
            % Optionally, load images now (or load on demand)
            imgs = cell(1, length(validFiles));
            for k = 1:length(validFiles)
                imgs{k} = imread(validFiles{k});
            end
            
            % Store in app properties
            app.ImageFiles = validFiles;
            app.ImageDates = validDates;
            app.Images = imgs;
            
            imagesc(app.P1ImageAxes, app.Images{1});
            axis(app.P1ImageAxes, 'image');
            app.P1ImageAxes.XTick = [];
            app.P1ImageAxes.YTick = [];
            drawnow;

            app.P1Slider.Limits = [1 length(app.Images)];
            app.P1Slider.MajorTicks = 1:length(app.Images);
            app.P1Slider.MajorTickLabels = cellstr(datestr(app.ImageDates, 'yyyy_MM'));
            app.P1Slider.Value = 1;
            app.P1Slider.MinorTicks = [];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Dimensions: same as MainContentPanel
            parent = app.MainContentPanel;
        
            % Panel 1
            app.ContentPanel1 = uipanel(parent, ...
                'Units', 'normalized', ...
                'Position', [0 0 620 400], ...
                'BackgroundColor', 'white', ...
                'Visible', 'on');
            
            % Create grid layout inside the panel
            layout = uigridlayout(app.ContentPanel1, [2, 1]);  % 2 rows, 1 column
            layout.RowHeight = {'1x', 50};
            
            % Create slider in the second row
            app.P1Slider = uislider(layout);
            app.P1Slider.Layout.Row = 2;
            app.P1Slider.Layout.Column = 1;
            app.P1Slider.MajorTicksMode = 'manual';
            app.P1Slider.MinorTicksMode = 'manual';
            app.P1Slider.ValueChangedFcn = @(src,event) sliderValueChanged(app, src, event);
            
            % Create image axes in the first row
            app.P1ImageAxes = uiaxes(layout);
            app.P1ImageAxes.Layout.Row = 1;
            app.P1ImageAxes.Layout.Column = 1;
            app.P1ImageAxes.XTick = [];
            app.P1ImageAxes.YTick = [];
            app.P1ImageAxes.XColor = 'none';
            app.P1ImageAxes.YColor = 'none';
            app.P1ImageAxes.Box = 'on';  % Optional, for visual border
        
            % Panel 2
            app.ContentPanel2 = uipanel(parent, ...
                'Units', 'normalized', ...
                'Position', [0 0 620 400], ...
                'BackgroundColor', 'white', ...
                'Visible', 'off');
        
            % Panel 3
            app.ContentPanel3 = uipanel(parent, ...
                'Units', 'normalized', ...
                'Position', [0 0 620 400], ...
                'BackgroundColor', 'white', ...
                'Visible', 'off');
        
            % Add some sample content
            uilabel(app.ContentPanel1, 'Text', 'Panel 1', 'Position', [10 10 100 22]);
            uilabel(app.ContentPanel2, 'Text', 'Panel 2', 'Position', [10 10 100 22]);
            uilabel(app.ContentPanel3, 'Text', 'Panel 3', 'Position', [10 10 100 22]);
        end

        % Value changed function: VisualizationStyleDropDown
        function VisualizationStyleDropDownValueChanged(app, event)
            val = app.VisualizationStyleDropDown.Value;
        
            % Hide all
            app.ContentPanel1.Visible = 'off';
            app.ContentPanel2.Visible = 'off';
            app.ContentPanel3.Visible = 'off';
        
            % Show selected
            switch val
                case 'Option 1'
                    app.ContentPanel1.Visible = 'on';
                case 'Option 2'
                    app.ContentPanel2.Visible = 'on';
                case 'Option 3'
                    app.ContentPanel3.Visible = 'on';
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            loadImagesFromFolder(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.Text = 'Quit';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.VisualizationStyleDropDownLabel = uilabel(app.GridLayout2);
            app.VisualizationStyleDropDownLabel.Text = 'Visualization Style';
            app.VisualizationStyleDropDown = uidropdown(app.GridLayout2);
            app.VisualizationStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualizationStyleDropDownValueChanged, true);
            app.MainContentPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cvApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end